classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        editgenEditField            matlab.ui.control.EditField
        editgenEditFieldLabel       matlab.ui.control.Label
        calculeazaSMIButton         matlab.ui.control.Button
        vesteaLabel                 matlab.ui.control.Label
        editinaltimeEditField       matlab.ui.control.NumericEditField
        editinaltimeEditFieldLabel  matlab.ui.control.Label
        incarcaimagineCTButton      matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: incarcaimagineCTButton
        function incarcaimagineCTButtonPushed(app, event)
            [fisier, cale] = uigetfile({'*.png;*.jpg;*.bmp'}, 'Selectează imaginea CT');
            if isequal(fisier, 0), return; end
            img = imread(fullfile(cale, fisier));
            app.UIFigure.UserData.ImagineBruta = img;
            imshow(img, 'Parent', app.UIAxes);
        end

        % Button pushed function: calculeazaSMIButton
        function calculeazaSMIButtonPushed(app, event)
            if ~isfield(app.UIFigure.UserData, 'ImagineBruta')
                uialert(app.UIFigure, 'Va rog sa incarcati o imagine CT!', 'Lipsa date');
                return;
            end
            
            persistent net;
            if isempty(net)
                temp = load('ModelFinalMuschi_V4.mat'); % Asigură-te că numele e corect
                net = temp.net;
            end
    
            imgOriginala = app.UIFigure.UserData.ImagineBruta;
            % preluare date de la medic
            h = app.editinaltimeEditField.Value;
            % se verifica daca inaltimea e valida 
            if h <= 0, uialert(app.UIFigure, 'Introduceti o inaltime valida', 'Eroare'); return; end
            
            imgOriginala = app.UIFigure.UserData.ImagineBruta;
            % procesare AI
            imgResized = imresize(imgOriginala, [512 832]);
            if size(imgResized, 3) > 1, imgResized = rgb2gray(imgResized); end
            imgSingle = im2single(adapthisteq(imgResized));
            
            scoreMap = predict(net, imgSingle);
            masca = scoreMap(:,:,2) > 0.2;
            masca = bwareaopen(masca, 100);
            
            % calcul SMI - estimarea ariei
            numarPixeli = sum(masca(:));
            SMA = (numarPixeli * 0.7 * 0.7) / 100; % cm2
            SMI = SMA / (h^2);
            
            % afisare
            imshow(labeloverlay(imgResized, masca), 'Parent', app.UIAxes);
            
            % vestea - praguri
            if SMI < 52.4
                app.vesteaLabel.Text = sprintf('Vestea: SMI = %.2f cm²/m² - RISC SARCOPENIE', SMI);
                app.vesteaLabel.FontColor = [1 0 0];
            else
                app.vesteaLabel.Text = sprintf('Vestea: SMI = %.2f cm²/m² - NORMAL', SMI);
                app.vesteaLabel.FontColor = [0 0.5 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'rezultatul segmentarii')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.incarcaimagineCTButton = uibutton(app.UIFigure, 'push');
            app.incarcaimagineCTButton.ButtonPushedFcn = createCallbackFcn(app, @incarcaimagineCTButtonPushed, true);
            app.incarcaimagineCTButton.Text = 'incarca imagine CT';
            app.editinaltimeEditFieldLabel = uilabel(app.UIFigure);
            app.editinaltimeEditFieldLabel.Text = 'edit inaltime';
            app.editinaltimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.vesteaLabel = uilabel(app.UIFigure);
            app.vesteaLabel.Text = 'vestea:';
            app.calculeazaSMIButton = uibutton(app.UIFigure, 'push');
            app.calculeazaSMIButton.ButtonPushedFcn = createCallbackFcn(app, @calculeazaSMIButtonPushed, true);
            app.calculeazaSMIButton.Text = 'calculeaza SMI';
            app.editgenEditFieldLabel = uilabel(app.UIFigure);
            app.editgenEditFieldLabel.Text = 'edit gen';
            app.editgenEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end